classdef DSP0_App_HW1_Q1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        AddPanel                 matlab.ui.container.Panel
        GridLayout5              matlab.ui.container.GridLayout
        AddButton                matlab.ui.control.Button
        TypeDropDownLabel        matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        RealEditFieldLabel       matlab.ui.control.Label
        RealEditField            matlab.ui.control.NumericEditField
        ImaginaryEditFieldLabel  matlab.ui.control.Label
        ImaginaryEditField       matlab.ui.control.NumericEditField
        GridLayout6              matlab.ui.container.GridLayout
        PlotSettingsButtonGroup  matlab.ui.container.ButtonGroup
        MagnitudelinearButton    matlab.ui.control.RadioButton
        MagnitudedBButton        matlab.ui.control.RadioButton
        PhaseargButton           matlab.ui.control.RadioButton
        PhaseARGButton           matlab.ui.control.RadioButton
        informationPanel         matlab.ui.container.Panel
        GridLayout7              matlab.ui.container.GridLayout
        NumofPolesLabel          matlab.ui.control.Label
        NumofZeroesLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a = 1; % Description for poles
        b = 1; % Description for poles
        poleNum = 0; %Setting the primitive values
        zeroNum = 0; %Setting the primitive values 
        h; %Impulse Response of system
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.TypeDropDown.Value == 'Pole' %Check if a pole is input
                if app.RealEditField.Value ~= 0 || app.ImaginaryEditField.Value ~= 0 %check if pole is not at z = 0
                    app.poleNum = app.poleNum + 1; %Increasing Number of poles
                end 
                
                app.NumofPolesLabel.Text = strcat('Num. of Poles :   ', int2str(app.poleNum));
                if app.a == 1
                    app.a = [1 -(app.RealEditField.Value + 1j*app.ImaginaryEditField.Value)];% vector a consisting a pole
                else
                    app.a = conv(app.a, [1 -(app.RealEditField.Value + 1j*app.ImaginaryEditField.Value)]);
                end
            else %% Input is zero of system
                if app.RealEditField.Value ~= 0 || app.ImaginaryEditField.Value ~= 0
                    app.zeroNum = app.zeroNum + 1; %Increasing Number of zeros
                end
                
                app.NumofZeroesLabel.Text = strcat('Num. of Zeroes : ', int2str(app.zeroNum));
                if app.b == 1
                    app.b = [1 -(app.RealEditField.Value + 1j*app.ImaginaryEditField.Value)]; % vector b consisting a zero
                else
                    app.b = conv(app.b, [1 -(app.RealEditField.Value + 1j*app.ImaginaryEditField.Value)]);
                end
            end
            app.h = freqz(app.b, app.a,1024); %Impulse Response of system
           if app.MagnitudelinearButton.Value
                plot(app.UIAxes, linspace(0, pi, 1024)/pi,abs(app.h));
                grid(app.UIAxes,'on');
                title(app.UIAxes,"Abs(Linear)");
                xlabel(app.UIAxes,"Normalized Frequency");
                ylabel(app.UIAxes,"Abs(Linear)");
            elseif app.MagnitudedBButton.Value
                 plot(app.UIAxes,  linspace(0, pi, 1024)/pi,20*log10(abs(app.h)));
                 grid(app.UIAxes,'on');
                 title(app.UIAxes,"Abs(db) of System");
                 xlabel(app.UIAxes,"Normalized Frequency");
                 ylabel(app.UIAxes,"ABS(db)");
            elseif app.PhaseargButton.Value
                plot(app.UIAxes,  linspace(0, pi, 1024)/pi,unwrap(angle((app.h))));
                grid(app.UIAxes,'on');
                title(app.UIAxes,"arg of System");
                xlabel(app.UIAxes,"Normalized Frequency");
                ylabel(app.UIAxes,"arg(degree)");
            else
                plot(app.UIAxes, linspace(0, pi, 1024)/pi,phase((app.h)));
                grid(app.UIAxes,'on');
                title(app.UIAxes,"ARG of System");
                xlabel(app.UIAxes,"Normalized Frequency");
                ylabel(app.UIAxes,"ARG(degree)");
           end
        end

        % Selection changed function: PlotSettingsButtonGroup
        function PlotSettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlotSettingsButtonGroup.SelectedObject;
            if app.MagnitudelinearButton.Value
                plot(app.UIAxes, linspace(0, pi, 1024)/pi,abs(app.h));
                grid(app.UIAxes,'on');
                title(app.UIAxes,"Abs(Linear)");
                xlabel(app.UIAxes,"Normalized Frequency");
                ylabel(app.UIAxes,"Abs(Linear)");
            elseif app.MagnitudedBButton.Value
                 plot(app.UIAxes,  linspace(0, pi, 1024)/pi,20*log10(abs(app.h)));
                 grid(app.UIAxes,'on');
                 title(app.UIAxes,"Abs(db) of System");
                 xlabel(app.UIAxes,"Normalized Frequency");
                 ylabel(app.UIAxes,"ABS(db)");
            elseif app.PhaseargButton.Value
                plot(app.UIAxes,  linspace(0, pi, 1024)/pi,unwrap(angle((app.h))));
                grid(app.UIAxes,'on');
                title(app.UIAxes,"arg of System");
                xlabel(app.UIAxes,"Normalized Frequency");
                ylabel(app.UIAxes,"arg(degree)");
            else
                plot(app.UIAxes, linspace(0, pi, 1024)/pi,phase((app.h)));
                grid(app.UIAxes,'on');
                title(app.UIAxes,"ARG of System");
                xlabel(app.UIAxes,"Normalized Frequency");
                ylabel(app.UIAxes,"ARG(degree)");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.AddPanel = uipanel(app.GridLayout2);
            app.AddPanel.Title = 'Add';
            app.GridLayout5 = uigridlayout(app.AddPanel);
            app.AddButton = uibutton(app.GridLayout5, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.TypeDropDownLabel = uilabel(app.GridLayout5);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.GridLayout5);
            app.TypeDropDown.Items = {'Pole', 'Zero'};
            app.TypeDropDown.Value = 'Pole';
            app.RealEditFieldLabel = uilabel(app.GridLayout5);
            app.RealEditFieldLabel.Text = 'Real';
            app.RealEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ImaginaryEditFieldLabel = uilabel(app.GridLayout5);
            app.ImaginaryEditFieldLabel.Text = 'Imaginary';
            app.ImaginaryEditField = uieditfield(app.GridLayout5, 'numeric');
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PlotSettingsButtonGroup = uibuttongroup(app.GridLayout6);
            app.PlotSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotSettingsButtonGroupSelectionChanged, true);
            app.PlotSettingsButtonGroup.Title = 'Plot Settings';
            app.MagnitudelinearButton = uiradiobutton(app.PlotSettingsButtonGroup);
            app.MagnitudelinearButton.Text = 'Magnitude (linear)';
            app.MagnitudelinearButton.Value = true;
            app.MagnitudedBButton = uiradiobutton(app.PlotSettingsButtonGroup);
            app.MagnitudedBButton.Text = 'Magnitude (dB)';
            app.PhaseargButton = uiradiobutton(app.PlotSettingsButtonGroup);
            app.PhaseargButton.Text = 'Phase (arg)';
            app.PhaseARGButton = uiradiobutton(app.PlotSettingsButtonGroup);
            app.PhaseARGButton.Text = 'Phase (ARG)';
            app.informationPanel = uipanel(app.GridLayout6);
            app.informationPanel.Title = 'information';
            app.GridLayout7 = uigridlayout(app.informationPanel);
            app.NumofPolesLabel = uilabel(app.GridLayout7);
            app.NumofPolesLabel.Text = 'Num. of Poles';
            app.NumofZeroesLabel = uilabel(app.GridLayout7);
            app.NumofZeroesLabel.Text = 'Num. of Zeroes';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, {'Frequency Response'; ''})
            xlabel(app.UIAxes, 'w')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP0_App_HW1_Q1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end