classdef DSP0_App_HW1_Q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        aEditFieldLabel            matlab.ui.control.Label
        aEditField                 matlab.ui.control.EditField
        MEditFieldLabel            matlab.ui.control.Label
        MEditField                 matlab.ui.control.EditField
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.EditField
        OriginalSignalPlotsettingsButtonGroup  matlab.ui.container.ButtonGroup
        FrequencyResponceButton    matlab.ui.control.RadioButton
        TimeDomainButton           matlab.ui.control.RadioButton
        PlotButton_2               matlab.ui.control.Button
        EchoSignalPlotsettingsButtonGroup  matlab.ui.container.ButtonGroup
        FrequencyResponceButton_2  matlab.ui.control.RadioButton
        TimeDomainButton_2         matlab.ui.control.RadioButton
        PlotButton                 matlab.ui.control.Button
        Panel                      matlab.ui.container.Panel
        OpenAudioFileButton        matlab.ui.control.Button
        PlayEchoSignalButton       matlab.ui.control.Button
        PlaySignalButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y %Original Signal
        pathname %path of signal
        filename % file that we store sound in it
        fs %frequency of the sound
        y_echo %Echo version of signal
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            [app.filename,app.pathname] = uigetfile('*.wav');
            if app.filename
                [app.y,app.fs] = audioread(fullfile(app.pathname,app.filename));
            app.y = app.y(:,1)';
            end
        end

        % Button pushed function: PlaySignalButton
        function PlaySignalButtonPushed(app, event)
            sound(app.y,app.fs); %Play the Original Signal
        end

        % Selection changed function: 
        % OriginalSignalPlotsettingsButtonGroup
        function OriginalSignalPlotsettingsButtonGroupSelectionChanged(app, event)
            selectedButton = app.OriginalSignalPlotsettingsButtonGroup.SelectedObject.Text;
            switch selectedButton
                case 'Time Domain'
                      to = 0 : 1/app.fs : (length(app.y)/app.fs)- 1/app.fs; %Define time for Original signal in the same length
                      plot(app.UIAxes,to,app.y,'Color',[0 1 1])
                      title(app.UIAxes,'Original Signal In TIme domain')
                      xlabel(app.UIAxes,'Time(sec)')
                      ylabel(app.UIAxes,'Amplitude')
                      
                case 'Frequency Responce'
                      [y_f , fo] = freqz(app.y,1024,'whole');
                      fo = fo/pi ;%Normalized Frequency with same length 
                      plot(app.UIAxes,fo,20*log(abs(y_f)),"k") %Magnitude in DB scale
                      title(app.UIAxes,'Original Signal In Freq domain')
                      xlabel(app.UIAxes,'frequency')
                      ylabel(app.UIAxes,'Amplitude')
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
               selectedButton =  app.OriginalSignalPlotsettingsButtonGroup.SelectedObject.Text;
               switch selectedButton
                   case 'Time Domain'
                      to = 0 : 1/app.fs : (length(app.y)/app.fs)- 1/app.fs; %Define time for Original signal in the same length
                      plot(app.UIAxes,to,app.y,'Color',[0 1 1])
                      title(app.UIAxes,'Original Signal In TIme domain')
                      xlabel(app.UIAxes,'Time(sec)')
                      ylabel(app.UIAxes,'Amplitude')
                      
                   case 'Frequency Responce'
                      [y_f , fo] = freqz(app.y,1024,'whole')
                      fo = fo/pi ;%Normalized Frequency with same length 
                      plot(app.UIAxes,fo,20*log(abs(y_f)),"k") %Magnitude in DB scale
                      title(app.UIAxes,'Original Signal In Freq domain')
                      xlabel(app.UIAxes,'frequency')
                      ylabel(app.UIAxes,'Amplitude')
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            selectedButton =  app.EchoSignalPlotsettingsButtonGroup.SelectedObject.Text;
            P = (length(app.y) + app.NEditField.Value*app.MEditField.Value) ; %First Allocation SIze
            app.y_echo = zeros( 1 , P ); %preAllocation
            for k = 0:app.NEditField.Value
                     G = (length(app.y)+ k*app.MEditField.Value) ;
                     y_h = zeros(1 , G ); %%Preallocation for Helping y
                 for i = 1:length(app.y)
                     y_h(i - k*app.MEditField.Value) = app.y(i); %sigma y[n - k*M]
                 end
                 app.y_echo = app.y_echo + ((app.aEditField.Value).^k).*y_h; %sigma a^k * y[n - k*M]
            end
            if strcmp(selectedButton, 'Time Domain')
                 te = 0 : 1/app.fs : (length(app.y_echo)/app.fs)- 1/app.fs; %Define time for EChoed signal in the same length
                 plot(app.UIAxes,te,app.y,'Color',[0 0 1])
                 title(app.UIAxes,'Echo Signal In TIme domain')
                 xlabel(app.UIAxes,'Time(sec)')
                 ylabel(app.UIAxes,'Amplitude')
            else
                  [y_Echof , fo] = freqz(app.y_echo,1024,'whole');
                  fo = fo/pi ;%Normalized Frequency with same length 
                  plot(app.UIAxes,fo,20*log(abs(y_Echof)),"k") %Magnitude in DB scale
                  title(app.UIAxes,'Echoed Signal In Freq domain')
                  xlabel(app.UIAxes,'frequency')
                  ylabel(app.UIAxes,'Amplitude')
            end
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            app.PlotButtonPushed;
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.PlotButtonPushed;
        end

        % Button pushed function: PlayEchoSignalButton
        function PlayEchoSignalButtonPushed(app, event)
            sound(app.y_echo,app.fs);%play echo signal
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            app.PlotButtonPushed;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Value = '0.7';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'text');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = '44100';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'text');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = '3';
            app.OriginalSignalPlotsettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.OriginalSignalPlotsettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OriginalSignalPlotsettingsButtonGroupSelectionChanged, true);
            app.OriginalSignalPlotsettingsButtonGroup.Title = 'Original Signal Plot settings';
            app.FrequencyResponceButton = uiradiobutton(app.OriginalSignalPlotsettingsButtonGroup);
            app.FrequencyResponceButton.Text = 'Frequency Responce';
            app.FrequencyResponceButton.Value = true;
            app.TimeDomainButton = uiradiobutton(app.OriginalSignalPlotsettingsButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.PlotButton_2 = uibutton(app.OriginalSignalPlotsettingsButtonGroup, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.EchoSignalPlotsettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.EchoSignalPlotsettingsButtonGroup.Title = 'Echo Signal Plot settings';
            app.FrequencyResponceButton_2 = uiradiobutton(app.EchoSignalPlotsettingsButtonGroup);
            app.FrequencyResponceButton_2.Text = 'Frequency Responce';
            app.FrequencyResponceButton_2.Value = true;
            app.TimeDomainButton_2 = uiradiobutton(app.EchoSignalPlotsettingsButtonGroup);
            app.TimeDomainButton_2.Text = 'Time Domain';
            app.PlotButton = uibutton(app.EchoSignalPlotsettingsButtonGroup, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.OpenAudioFileButton = uibutton(app.Panel, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.PlayEchoSignalButton = uibutton(app.Panel, 'push');
            app.PlayEchoSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayEchoSignalButtonPushed, true);
            app.PlayEchoSignalButton.Text = 'Play(Echo Signal)';
            app.PlaySignalButton = uibutton(app.Panel, 'push');
            app.PlaySignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySignalButtonPushed, true);
            app.PlaySignalButton.Text = 'Play(Signal)';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Echo Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP0_App_HW1_Q2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end